classdef WeightMeasureApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ActionsDropDownLabel       matlab.ui.control.Label
        ActionsDropDown            matlab.ui.control.DropDown
        CurrentActionDisplayPanel  matlab.ui.container.Panel
        StartButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        arduino = arduino();
        isInCurrentMeasure = false;
        dailyTimer % Description
    end
    
    methods (Access = private)
        
        function currentMeasure(app)     
            app.isInCurrentMeasure = true;
            
            if ~isempty(app.dailyTimer)
                stop(app.dailyTimer);
            end
            
            delete(app.CurrentActionDisplayPanel.Children);
            
            ax = uiaxes(app.CurrentActionDisplayPanel);
            ax.Position = [ax.Position(1:2) [app.CurrentActionDisplayPanel.Position(3:4) - 3.5*ax.Position(1:2)]];
            xLabel = 'Elapsed Time (s)';
            yLabel = 'Weight (Kg)';
            yMax  = 4;
            yMin  = 0;
            plotGrid = 'on';
        
            bufferSize = 60;
            time_plot = zeros(1, bufferSize);
            data_plot = zeros(1, bufferSize);
        
            plotGraph = plot(ax, 0,0,'-r');
            xlabel(ax, xLabel);
            ylabel(ax, yLabel);
            ylim(ax, [yMin yMax]);
            grid(ax, plotGrid);
        
            tic;
            while app.isInCurrentMeasure
                voltage = app.arduino.readVoltage('A0');
                weight = FSRValue(voltage);
                
                disp('---------');
                disp(weight)
                disp(voltage);
        
                time_plot = [time_plot(2:end) toc];
                data_plot = [data_plot(2:end) weight];
                set(plotGraph, 'XData', time_plot, 'YData', data_plot);    
                xlim(ax, [time_plot(1) time_plot(end)]);
            end
        end
        
        function dailyMeasure(app, ~, ~, ~)
            filename = strcat('weights-', datestr(datetime('today')), '.mat');
            voltage = app.arduino.readVoltage('A0');
            weight = FSRValue(voltage);
            currentDateTime = datetime;
            
            disp('Updating weights file...');
            
            if ~exist(filename, "file")
                save(filename, 'currentDateTime', 'weight');
            else
                m = matfile(filename,'Writable',true);
                currentDateTime = [m.currentDateTime currentDateTime];
                weight = [m.weight weight];
                save(filename, 'currentDateTime', 'weight');
            end
        end
        
        function dailyReport(app)
            app.isInCurrentMeasure = false;
            filename = strcat('weights-', datestr(datetime('today')), '.mat');
            
            delete(app.CurrentActionDisplayPanel.Children);
            
            ax = uiaxes(app.CurrentActionDisplayPanel);
            ax.Position = [ax.Position(1:2) [app.CurrentActionDisplayPanel.Position(3:4) - 3.5*ax.Position(1:2)]];
            xLabel = 'DateTime';
            yLabel = 'Weight (Kg)';
            plotGrid = 'on';
            
            
            if exist(filename, "file")
                m = matfile(filename,'Writable',false);
                plot(ax, m.currentDateTime, m.weight,'-r');
            else
                uialert(app.UIFigure,'Daily report not found. Did you start daily measuring?', 'File not found');
            end
            
            
            xlabel(ax, xLabel);
            ylabel(ax, yLabel);
            grid(ax, plotGrid);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ActionsDropDown
        function ActionsDropDownValueChanged(app, event)
            value = app.ActionsDropDown.Value;
            switch value
                case "Real Time Measure"
                    if app.isInCurrentMeasure
                        app.StartButton.Text = 'Stop';
                    else
                        app.StartButton.Text = 'Start';                    
                    end
                case "Start Saving Daily Data"
                    timerStatus = get(app.dailyTimer, 'Running');
                    if isequal(timerStatus ,'on')
                        app.StartButton.Text = 'Stop';
                    else
                        app.StartButton.Text = 'Start';
                    end
                    
                case "Daily report"
                    app.StartButton.Text = 'Start';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isInCurrentMeasure = false;
            if ~isempty(app.dailyTimer)
                stop(app.dailyTimer);
                delete(app.dailyTimer);
            end
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            value = app.ActionsDropDown.Value;
            switch value
                case "Real Time Measure"
                    if app.isInCurrentMeasure
                        app.isInCurrentMeasure = false;
                        app.StartButton.Text = 'Start';
                    else 
                        timerStatus = get(app.dailyTimer, 'Running');
                        if isequal(timerStatus ,'on')
                            confirmation = uiconfirm(app.UIFigure,'When real-time measuring is activated, the daily data-collector must be stopped. Do you want to procceed?','Warning');
                            
                            if(isequal(confirmation, 'OK'))
                                app.StartButton.Text = 'Stop';
                                currentMeasure(app);  
                            end
                        else
                            app.StartButton.Text = 'Stop';
                            currentMeasure(app);
                        end
                    end
                case "Start Saving Daily Data"
                    app.isInCurrentMeasure = false;
                    
                    if isempty(app.dailyTimer)
                        app.dailyTimer = timer( ...
                            'StartDelay', 0, ...
                            'Period', 60, ...
                            'TasksToExecute', Inf, ...
                            'ExecutionMode', 'fixedRate', ...
                            'UserData', app ...
                        );
                        app.dailyTimer.TimerFcn = @app.dailyMeasure;
                    end
                    
                    timerStatus = get(app.dailyTimer, 'Running');
                    if ~isequal(timerStatus ,'on')
                        start(app.dailyTimer);
                        app.StartButton.Text = 'Stop';
                    else
                        stop(app.dailyTimer);
                        app.StartButton.Text = 'Start';
                    end
                    
                case "Daily report"
                    dailyReport(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ActionsDropDownLabel = uilabel(app.UIFigure);
            app.ActionsDropDownLabel.Text = 'Actions';
            app.ActionsDropDown = uidropdown(app.UIFigure);
            app.ActionsDropDown.Items = {'Real Time Measure', 'Start Saving Daily Data', 'Daily report'};
            app.ActionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionsDropDownValueChanged, true);
            app.ActionsDropDown.Value = 'Real Time Measure';
            app.CurrentActionDisplayPanel = uipanel(app.UIFigure);
            app.CurrentActionDisplayPanel.Title = 'Current Action Display';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WeightMeasureApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end